classdef cv_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout2                matlab.ui.container.GridLayout
        TabGroup                   matlab.ui.container.TabGroup
        DashboardTab               matlab.ui.container.Tab
        GridLayout4                matlab.ui.container.GridLayout
        GridLayout10               matlab.ui.container.GridLayout
        Panel_7                    matlab.ui.container.Panel
        GridLayout16_6             matlab.ui.container.GridLayout
        GeologicalLocationLabel    matlab.ui.control.Label
        Panel_6                    matlab.ui.container.Panel
        GridLayout16_5             matlab.ui.container.GridLayout
        GridLayout20_2             matlab.ui.container.GridLayout
        EndImgFilenameLabel        matlab.ui.control.Label
        EndImageLabel              matlab.ui.control.Label
        GridLayoutEndImage         matlab.ui.container.GridLayout
        Panel_5                    matlab.ui.container.Panel
        GridLayout16_4             matlab.ui.container.GridLayout
        GridLayout20               matlab.ui.container.GridLayout
        StartImgFilenameLabel      matlab.ui.control.Label
        StartImageLabel            matlab.ui.control.Label
        GridLayoutStartImage       matlab.ui.container.GridLayout
        GridLayout8                matlab.ui.container.GridLayout
        Panel_2                    matlab.ui.container.Panel
        GridLayout16               matlab.ui.container.GridLayout
        GridLayout19               matlab.ui.container.GridLayout
        PreviewFilenameLabel       matlab.ui.control.Label
        ImagePreviewLabel          matlab.ui.control.Label
        GridLayoutImagePreview     matlab.ui.container.GridLayout
        UIAxesImagePreview         matlab.ui.control.UIAxes
        GridLayout9                matlab.ui.container.GridLayout
        Panel_4                    matlab.ui.container.Panel
        GridLayout16_3             matlab.ui.container.GridLayout
        GridLayout18               matlab.ui.container.GridLayout
        QuickSelectSlider          matlab.ui.control.Slider
        QuickSelectSliderLabel     matlab.ui.control.Label
        PreviewModeDropDown        matlab.ui.control.DropDown
        PreviewModeDropDownLabel   matlab.ui.control.Label
        ImageSelectDropDown        matlab.ui.control.DropDown
        ImageSelectDropDownLabel   matlab.ui.control.Label
        ImagePreviewSettingLabel   matlab.ui.control.Label
        Panel_3                    matlab.ui.container.Panel
        GridLayout16_2             matlab.ui.container.GridLayout
        GridLayout22               matlab.ui.container.GridLayout
        ImageSetNameLabel          matlab.ui.control.Label
        ImageSetNameTagLabel       matlab.ui.control.Label
        ImageSetInformationLabel   matlab.ui.control.Label
        GridLayout6                matlab.ui.container.GridLayout
        GridLayout7_4              matlab.ui.container.GridLayout
        ImageSizeLabel             matlab.ui.control.Label
        CroppedImageSizeLabel      matlab.ui.control.Label
        GridLayout7_3              matlab.ui.container.GridLayout
        MonthLabel                 matlab.ui.control.Label
        TimeBetweenImagesLabel     matlab.ui.control.Label
        GridLayout7_2              matlab.ui.container.GridLayout
        YearsLabel                 matlab.ui.control.Label
        TimespanLabel              matlab.ui.control.Label
        GridLayout7                matlab.ui.container.GridLayout
        ImagesLabel                matlab.ui.control.Label
        ImageLoadedLabel           matlab.ui.control.Label
        SliderViewTab              matlab.ui.container.Tab
        GridLayout4_2              matlab.ui.container.GridLayout
        GridLayout10_2             matlab.ui.container.GridLayout
        Panel_13                   matlab.ui.container.Panel
        Panel_12                   matlab.ui.container.Panel
        Panel_11                   matlab.ui.container.Panel
        GridLayout8_2              matlab.ui.container.GridLayout
        Panel_39                   matlab.ui.container.Panel
        Panel_8                    matlab.ui.container.Panel
        DifferenceHighlightTab     matlab.ui.container.Tab
        GridLayout4_3              matlab.ui.container.GridLayout
        GridLayout10_3             matlab.ui.container.GridLayout
        Panel_21                   matlab.ui.container.Panel
        Panel_17                   matlab.ui.container.Panel
        GridLayout8_3              matlab.ui.container.GridLayout
        Panel_14                   matlab.ui.container.Panel
        GridLayout9_3              matlab.ui.container.GridLayout
        Panel_22                   matlab.ui.container.Panel
        TimelapseTab               matlab.ui.container.Tab
        GridLayout4_4              matlab.ui.container.GridLayout
        GridLayout10_4             matlab.ui.container.GridLayout
        Panel_26                   matlab.ui.container.Panel
        Panel_25                   matlab.ui.container.Panel
        GridLayout8_4              matlab.ui.container.GridLayout
        Panel_23                   matlab.ui.container.Panel
        GridLayout9_4              matlab.ui.container.GridLayout
        Panel_24                   matlab.ui.container.Panel
        FlickerViewTab             matlab.ui.container.Tab
        GridLayout4_5              matlab.ui.container.GridLayout
        GridLayout10_5             matlab.ui.container.GridLayout
        Panel_30                   matlab.ui.container.Panel
        Panel_29                   matlab.ui.container.Panel
        GridLayout8_5              matlab.ui.container.GridLayout
        Panel_27                   matlab.ui.container.Panel
        GridLayout9_5              matlab.ui.container.GridLayout
        Panel_28                   matlab.ui.container.Panel
        SemanticSegmentationTab    matlab.ui.container.Tab
        GridLayout4_6              matlab.ui.container.GridLayout
        GridLayout10_6             matlab.ui.container.GridLayout
        Panel_34                   matlab.ui.container.Panel
        Panel_33                   matlab.ui.container.Panel
        GridLayout8_6              matlab.ui.container.GridLayout
        Panel_31                   matlab.ui.container.Panel
        GridLayout9_6              matlab.ui.container.GridLayout
        Panel_32                   matlab.ui.container.Panel
        ReportTab                  matlab.ui.container.Tab
        GridLayout4_7              matlab.ui.container.GridLayout
        GridLayout10_7             matlab.ui.container.GridLayout
        Panel_38                   matlab.ui.container.Panel
        Panel_37                   matlab.ui.container.Panel
        GridLayout8_7              matlab.ui.container.GridLayout
        Panel_35                   matlab.ui.container.Panel
        GridLayout9_7              matlab.ui.container.GridLayout
        Panel_36                   matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        EarthViewerGUITeam25Label  matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        GridLayout11               matlab.ui.container.GridLayout
        GridLayout13               matlab.ui.container.GridLayout
        TabGroup2                  matlab.ui.container.TabGroup
        OverviewTab                matlab.ui.container.Tab
        GridLayout14               matlab.ui.container.GridLayout
        GridLayout21               matlab.ui.container.GridLayout
        EndImageDropDown           matlab.ui.control.DropDown
        DropDown_2Label            matlab.ui.control.Label
        StartImageDropDown         matlab.ui.control.DropDown
        Label                      matlab.ui.control.Label
        Step2Label                 matlab.ui.control.Label
        Step1Label                 matlab.ui.control.Label
        SelectInputFilesButton     matlab.ui.control.Button
        FilesTab_2                 matlab.ui.container.Tab
        GridLayout15               matlab.ui.container.GridLayout
        FileTree                   matlab.ui.container.CheckBoxTree
        NoFilesLoadedNode          matlab.ui.container.TreeNode
        LoadedFilesLabel           matlab.ui.control.Label
        SettingsTab                matlab.ui.container.Tab
        GridLayout14_2             matlab.ui.container.GridLayout
        SettingsLabel              matlab.ui.control.Label
        GridLayout12               matlab.ui.container.GridLayout
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = private)
        StartImage matlab.ui.control.Image
        EndImage matlab.ui.control.Image
        
        DashboardDropdownItems string

        PreviewImageData table
        StartImageData table
        EndImageData table
        
        ImageDatasets cell

        AlignedImages cell

        CroppedImages cell

        CurrentLoadedTable table

        DashboardPreviewMode string
        
    end
    
    methods (Access = private)
        % Dashboard Preview Dropdown
        function updateDropdown(app)
            if isvalid(app.ImageSelectDropDown)
                app.ImageSelectDropDown.Items = cellstr(app.DashboardDropdownItems);
                if ~isempty(app.DashboardDropdownItems)
                    app.ImageSelectDropDown.Value = app.DashboardDropdownItems(1);
                end
            end
        end
        
        % Sidebar Image Selection Dropdown
        function updateSidebarDropdown(app)
            if isvalid(app.StartImageDropDown)
                % Exclude last item for start dropdown
                startItems = app.DashboardDropdownItems(1:end-1);
                app.StartImageDropDown.Items = cellstr(startItems);
                if ~isempty(startItems)
                    app.StartImageDropDown.Value = startItems(1);
                end
            end
            if isvalid(app.EndImageDropDown)
                % Exclude first item for end dropdown
                endItems = app.DashboardDropdownItems(2:end);
                app.EndImageDropDown.Items = cellstr(endItems);
                if ~isempty(endItems)
                    app.EndImageDropDown.Value = endItems(end);
                end
            end
        end

        % Dashboard Preview Image
        function updatePreview(app)
            axis(app.UIAxesImagePreview, 'image');
            app.UIAxesImagePreview.Toolbar.Visible = 'on';
            previewImage = app.imageLoadController(app.PreviewImageData);
            imshow(previewImage, 'Parent', app.UIAxesImagePreview);
            app.PreviewFilenameLabel.Text = app.PreviewImageData.Name{1};
        end

        % Dashboard Start Image
        function updateStart(app)
            if isempty(app.StartImage) || ~isvalid(app.StartImage)
                app.StartImage = uiimage(app.GridLayoutStartImage); 
                app.StartImage.ScaleMethod = 'fit';
            end
            app.StartImage.ImageSource = imread(app.StartImageData.Path{1});
            app.StartImgFilenameLabel.Text = app.StartImageData.Name{1};
        end
        
        % Dashboard End Image
        function updateEnd(app)
            if isempty(app.EndImage) || ~isvalid(app.EndImage)
                app.EndImage = uiimage(app.GridLayoutEndImage); 
                app.EndImage.ScaleMethod = 'fit';
            end
            app.EndImage.ImageSource = imread(app.EndImageData.Path{1});
            app.EndImgFilenameLabel.Text = app.EndImageData.Name{1};
        end

        % Dashboard Stat Cards
        function updateStats(app)
            numImages = height(app.CurrentLoadedTable);
            app.ImagesLabel.Text = string(numImages) + " Images";
            
            timestamps = app.CurrentLoadedTable.Timestamp;
            % Calculate diffference
            startTimestamp = min(timestamps);
            endTimestamp = max(timestamps);
            
            startYear = floor(startTimestamp / 100);
            startMonth = mod(startTimestamp, 100);
            endYear = floor(endTimestamp / 100);
            endMonth = mod(endTimestamp, 100);
            
            yearsDiff = endYear - startYear;
            monthsDiff = endMonth - startMonth;
            
            totalMonths = yearsDiff * 12 + monthsDiff;
            totalYears = totalMonths / 12;

            app.YearsLabel.Text = string(totalYears) + " Years";
            app.MonthLabel.Text = string(totalMonths / numImages) + " Month";
        end
        
        % Sidebar File Tree
        function updateFileTree(app)
            % Clear existing nodes
            app.FileTree.CheckedNodes = [];
            delete(app.FileTree.Children);
        
            % Initialize
            checkedNodes = app.FileTree.empty;
            nodeMap = containers.Map;
        
            % Build tree and node map
            for i = 1:numel(app.ImageDatasets)
                dataset = app.ImageDatasets{i};
                parentNode = uitreenode(app.FileTree, 'Text', dataset.Name);
                for j = 1:height(dataset.Table)
                    imageName = dataset.Table.Name{j};
                    childNode = uitreenode(parentNode, 'Text', imageName);
                    key = [dataset.Name '|' imageName];
                    nodeMap(key) = childNode;
                end
            end
        
            % Find checked nodes
            for i = 1:height(app.CurrentLoadedTable)
                imageName = app.CurrentLoadedTable.Name{i};
                for d = 1:numel(app.ImageDatasets)
                    dataset = app.ImageDatasets{d};
                    if any(strcmp(dataset.Table.Name, imageName))
                        key = [dataset.Name '|' imageName];
                        if nodeMap.isKey(key)
                            checkedNodes(end+1) = nodeMap(key); %#ok<AGROW>
                        end
                        break;
                    end
                end
            end
        
            % Apply checked nodes
            app.FileTree.CheckedNodes = checkedNodes;
        end
        
        % Dashboard QuickSelect Slider
        function updateSlider(app)
            numImages = height(app.CurrentLoadedTable);
            app.QuickSelectSlider.Limits = [1, numImages];
            app.QuickSelectSlider.MajorTicks = 1:numImages; 
            app.QuickSelectSlider.MajorTickLabels = string(1:numImages);
            app.QuickSelectSlider.Value = 1;
        end

        % Dashboard Image Set Information Board
        function updateImageSetInformation(app)
            app.ImageSetNameLabel.Text = app.CurrentLoadedTable.Properties.UserData;
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % modified from loadImageFolder.m
        function imgTable = loadImageFolder(app)

            % Load and sort all image files from selected folder into a table.
            startingFolder = fullfile(pwd, 'data');
            folder = uigetdir(startingFolder);

            if folder == 0, imgTable = []; return; end
        
            % Supported image extensions
            exts = {'*.jpg', '*.jpeg', '*.png', '*.bmp'};
            files = [];
            for i = 1:length(exts)
                files = [files; dir(fullfile(folder, exts{i}))]; %#ok<AGROW>
            end
        
            if numel(files) < 2
                errordlg('At least two images are required.', 'Error');
                imgTable = [];
                return;
            end
        
            % Extract timestamps (year and optional month) from filenames
            names = {files.name};
            timestamps = zeros(1, numel(names));
            for i = 1:numel(names)
                tokens = regexp(names{i}, '(\d{4})[_\-\.]?(\d{2})', 'tokens', 'once');
                if ~isempty(tokens)
                    year = str2double(tokens{1});
                    month = str2double(tokens{2});
                    timestamps(i) = year * 100 + month;
                else
                    yearOnly = regexp(names{i}, '\d{4}', 'match', 'once');
                    if ~isempty(yearOnly)
                        year = str2double(yearOnly);
                        timestamps(i) = year * 100;
                    else
                        timestamps(i) = Inf; % invalid or no date info
                    end
                end
            end
        
            % Sort by timestamp (ascending)
            [~, sortedIdx] = sort(timestamps);
            sortedNames = names(sortedIdx);
            sortedFiles = files(sortedIdx);
            sortedTimestamps = timestamps(sortedIdx);
            
            % Add folder name as dataset name to distinguish between datasets
            [~, folderName, ~] = fileparts(folder);
            fullNames = string(folderName) + " " + string(sortedNames(:));

            % Build output table
            paths = fullfile(folder, {sortedFiles.name}');
            imgTable = table(fullNames, paths(:), sortedTimestamps(:),'VariableNames', {'Name', 'Path', 'Timestamp'});
            app.CurrentLoadedTable = imgTable;
            app.CurrentLoadedTable.Properties.UserData = folderName;
        end

        % modified from martinTest.m
        function preproccessImages(app)
            numImages = height(app.CurrentLoadedTable);
            imgs = cell(1, numImages);
            for i = 1:numImages
                imgs{i} = im2double(imread(app.CurrentLoadedTable.Path{i}));
            end

            % ⇨ Bilder alignen
            [alignedImgs, tforms] = preprocessImageSequence(imgs);
            app.AlignedImages = alignedImgs;

            % ⇨ Gemeinsamer Ausschnitt (automatisch zugeschnitten)
            croppedImgs = cropToCommonRegion(alignedImgs);
            app.CroppedImages = croppedImgs;
            
            % Update Stat Cards
            [imgHeight, imgWidth, ~] = size(croppedImgs{1});
            app.ImageSizeLabel.Text = string(imgWidth) + " x " + string(imgHeight);
        end
        
        % controller for which image is selected
        function img = imageLoadController(app, imgInfo)
            if strcmp(app.DashboardPreviewMode, "Aligned")
                idx = find(strcmp(app.CurrentLoadedTable.Name, imgInfo.Name{1}), 1);
                if ~isempty(idx)
                    img = app.AlignedImages{idx};
                end
            elseif strcmp(app.DashboardPreviewMode, "Cropped")
                idx = find(strcmp(app.CurrentLoadedTable.Name, imgInfo.Name{1}), 1);
                if ~isempty(idx)
                    img = app.CroppedImages{idx};
                end
            else
                img = im2double(imread(imgInfo.Path{1}));
            end 
        end
        
        % Dataset Manager
        function addDataset(app,dataset)
            % Extract dataset name from folder
            datasetName = app.getDatasetName(dataset);
            
            % Check for duplicate datasets by name
            if isempty(app.ImageDatasets)
                existingNames = {};
            else
                existingNames = cellfun(@(d) d.Name, app.ImageDatasets, 'UniformOutput', false);
            end
        
            if any(strcmp(existingNames, datasetName))
                warning("Dataset '%s' already loaded.", datasetName);
            else
                % Store dataset as struct inside ImageDatasets
                newDataset = struct('Name', datasetName, 'Table', dataset);
                app.ImageDatasets{end+1} = newDataset; 
            end
        end
        
        % Get dataset name from path lazy copy pasta
        function datasetName = getDatasetName(~,dataset)
            firstImagePath = dataset.Path{1};
            [folderPath, ~, ~] = fileparts(firstImagePath);
            [~, datasetName] = fileparts(folderPath);
        end

        % Display Dataset
        function displayDataset(app, dataset)
            firstImage = dataset(1, :);
            lastImage = dataset(end, :);

            % PreviewImage UI Load (Interactive)
            app.PreviewImageData = firstImage;
            app.updatePreview();
            
            % StartImage UI Load
            app.StartImageData = firstImage;
            app.updateStart();

            % EndImage UI Load
            app.EndImageData = lastImage;
            app.updateEnd();   

            % Update Dropdown
            app.DashboardDropdownItems = dataset.Name;
            app.updateDropdown();
            
            app.updateStats();
            app.updateFileTree();
            app.updateSlider();
            app.updateSidebarDropdown();
            app.updateImageSetInformation();
        end

        function updateDatasetFromSidebarSelection(app)
            % Selected start and end names
            startName = app.StartImageDropDown.Value;
            endName = app.EndImageDropDown.Value;
        
            % Locate dataset
            datasetName = app.getDatasetName(app.CurrentLoadedTable);
            dataset = app.ImageDatasets{strcmp(datasetName, cellfun(@(d) d.Name, app.ImageDatasets, 'UniformOutput', false))};
            allNames = dataset.Table.Name;
        
            % Find indices
            idxStart = find(strcmp(allNames, startName), 1);
            idxEnd = find(strcmp(allNames, endName), 1);
            if isempty(idxStart) || isempty(idxEnd) || idxEnd < idxStart, return; end
        
            % Update only if start or end differs
            currentNames = app.CurrentLoadedTable.Name;
            if ~strcmp(currentNames{1}, startName) || ~strcmp(currentNames{end}, endName)
                app.CurrentLoadedTable = dataset.Table(idxStart:idxEnd, :);
                app.CurrentLoadedTable.Properties.UserData = datasetName;
                app.preproccessImages();
                app.displayDataset(app.CurrentLoadedTable);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: SelectInputFilesButton
        function SelectInputFilesButtonPushed(app, event)
            % Select folder and load image files
            imgTable = app.loadImageFolder();

            if height(imgTable) < 2, return; end
            
            app.addDataset(imgTable);

            app.displayDataset(imgTable);

            % Refocus app
            figure(app.UIFigure);
            
            % Start Preprocess in background
            app.preproccessImages();
            app.updatePreview();
        end

        % Value changed function: ImageSelectDropDown
        function ImageSelectDropDownValueChanged(app, event)
            value = app.ImageSelectDropDown.Value;
            if ~strcmp(app.PreviewImageData.Name, value)
                idx = find(strcmp(app.CurrentLoadedTable.Name, value), 1);
                if ~isempty(idx)
                    app.PreviewImageData = app.CurrentLoadedTable(idx, :);
                    app.updatePreview();
                end
            end
        end

        % Value changing function: QuickSelectSlider
        function QuickSelectSliderValueChanging(app, event)
            selectedIdx = round(event.Value);
            selectedIdx = min(max(selectedIdx, 1), height(app.CurrentLoadedTable));

            currentIdx = find(strcmp(app.CurrentLoadedTable.Name, app.PreviewImageData.Name), 1);
            
            if isempty(currentIdx) || currentIdx ~= selectedIdx
                app.PreviewImageData = app.CurrentLoadedTable(selectedIdx, :);
                app.updatePreview();
            end
        end

        % Value changed function: PreviewModeDropDown
        function PreviewModeDropDownValueChanged(app, event)
            app.DashboardPreviewMode = app.PreviewModeDropDown.Value;
            app.updatePreview();
            app.updateStart();
            app.updateEnd();
        end

        % Selection changed function: FileTree
        function FileTreeSelectionChanged(app, event)
            selectedNodes = app.FileTree.SelectedNodes;
            if ~isa(selectedNodes.Parent, 'matlab.ui.container.CheckBoxTree')
                selectedNodesName = selectedNodes(1).Text;
                % Copy Pasta 
                if ~strcmp(app.PreviewImageData.Name, selectedNodesName)
                    idx = find(strcmp(app.CurrentLoadedTable.Name, selectedNodesName), 1);
                    if ~isempty(idx)
                        app.PreviewImageData = app.CurrentLoadedTable(idx, :);
                        app.updatePreview();
                    end
                end
            end
        end

        % Callback function: FileTree
        function FileTreeCheckedNodesChanged(app, event)
            checkedNodes = app.FileTree.CheckedNodes;
            currentDatasetName = app.getDatasetName(app.CurrentLoadedTable);
   
            % Filter for root-level node and exclude current dataset
            rootNodes = checkedNodes([checkedNodes.Parent] == app.FileTree);
            rootNodes = rootNodes(~strcmp({rootNodes.Text}, currentDatasetName));
        
            % If another dataset is checked, switch to it
            if ~isempty(rootNodes)
                datasetName = rootNodes(1).Text;
                idx = find(cellfun(@(d) strcmp(d.Name, datasetName), app.ImageDatasets), 1);
                if ~isempty(idx)
                    dataset = app.ImageDatasets{idx};
                    app.CurrentLoadedTable = dataset.Table;
                    app.CurrentLoadedTable.Properties.UserData = datasetName;
                    app.preproccessImages();
                    app.displayDataset(dataset.Table);
                    return;
                else
                    warning('Dataset "%s" not found in ImageDatasets.', datasetName);
                end
            else
                % No dataset switch — check changes in subnode selection
                parentNode = findobj(app.FileTree.Children, 'Text', currentDatasetName);
                if ~isempty(parentNode)
                    subNodes = checkedNodes([checkedNodes.Parent] == parentNode);
                    selectedNames = {subNodes.Text};
                    currentNames = app.CurrentLoadedTable.Name;
            
                    % Update only if selected images changed
                    if ~isequal(sort(selectedNames), sort(currentNames))
                        dataset = app.ImageDatasets{strcmp(currentDatasetName, cellfun(@(d) d.Name, app.ImageDatasets, 'UniformOutput', false))};
                        newTable = dataset.Table(ismember(dataset.Table.Name, selectedNames), :);
                        newTable.Properties.UserData = currentDatasetName;
                        app.CurrentLoadedTable = newTable;
                        app.CurrentLoadedTable.Properties.UserData = app.getDatasetName(newTable);
                        app.preproccessImages();
                        app.displayDataset(app.CurrentLoadedTable);
                    end
                end
            end
        end

        % Value changed function: StartImageDropDown
        function StartImageDropDownValueChanged(app, event)
            value = app.StartImageDropDown.Value;
            startIdx = find(strcmp(app.DashboardDropdownItems, value));

            % Limit End Image Dropdown to images at or after Start Image
            validEndItems = app.DashboardDropdownItems(startIdx+1:end);
            app.EndImageDropDown.Items = validEndItems;
            
            % Reset End Image selection if it was before the new start index
            if ~ismember(app.EndImageDropDown.Value, validEndItems)
                app.EndImageDropDown.Value = validEndItems(end);
            end
            app.updateDatasetFromSidebarSelection()
        end

        % Value changed function: EndImageDropDown
        function EndImageDropDownValueChanged(app, event)
            value = app.EndImageDropDown.Value;
            endIdx = find(strcmp(app.DashboardDropdownItems, value));

            % Limit First Image Dropdown to images at or after Start Image
            validStartItems = app.DashboardDropdownItems(1:endIdx-1);
            app.StartImageDropDown.Items = validStartItems;
            
            % Reset First Image selection if it was before the new end index
            if ~ismember(app.EndImageDropDown.Value, validStartItems)
                app.StartImageDropDown.Value = validStartItems(1); 
            end
            app.updateDatasetFromSidebarSelection()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout11 = uigridlayout(app.Panel);
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.Image = uiimage(app.GridLayout12);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'gui', 'img', 'title.svg');
            app.GridLayout13 = uigridlayout(app.GridLayout11);
            app.TabGroup2 = uitabgroup(app.GridLayout13);
            app.OverviewTab = uitab(app.TabGroup2);
            app.OverviewTab.Title = 'Overview';
            app.GridLayout14 = uigridlayout(app.OverviewTab);
            app.SelectInputFilesButton = uibutton(app.GridLayout14, 'push');
            app.SelectInputFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputFilesButtonPushed, true);
            app.SelectInputFilesButton.Icon = fullfile(pathToMLAPP, 'gui', 'img', 'icon_files.svg');
            app.SelectInputFilesButton.Text = 'Select Input Files';
            app.Step1Label = uilabel(app.GridLayout14);
            app.Step1Label.Text = 'Step 1';
            app.Step2Label = uilabel(app.GridLayout14);
            app.Step2Label.Text = 'Step 2';
            app.GridLayout21 = uigridlayout(app.GridLayout14);
            app.Label = uilabel(app.GridLayout21);
            app.Label.Text = '';
            app.StartImageDropDown = uidropdown(app.GridLayout21);
            app.StartImageDropDown.Items = {};
            app.StartImageDropDown.ValueChangedFcn = createCallbackFcn(app, @StartImageDropDownValueChanged, true);
            app.StartImageDropDown.Placeholder = 'Select First / Start Image';
            app.StartImageDropDown.Value = {};
            app.DropDown_2Label = uilabel(app.GridLayout21);
            app.DropDown_2Label.Text = '';
            app.EndImageDropDown = uidropdown(app.GridLayout21);
            app.EndImageDropDown.Items = {};
            app.EndImageDropDown.ValueChangedFcn = createCallbackFcn(app, @EndImageDropDownValueChanged, true);
            app.EndImageDropDown.Placeholder = 'Select Second / End Image';
            app.EndImageDropDown.Value = {};
            app.FilesTab_2 = uitab(app.TabGroup2);
            app.FilesTab_2.Title = 'Files';
            app.GridLayout15 = uigridlayout(app.FilesTab_2);
            app.LoadedFilesLabel = uilabel(app.GridLayout15);
            app.LoadedFilesLabel.Text = 'Loaded Files: ';
            app.FileTree = uitree(app.GridLayout15, 'checkbox');
            app.FileTree.SelectionChangedFcn = createCallbackFcn(app, @FileTreeSelectionChanged, true);
            app.NoFilesLoadedNode = uitreenode(app.FileTree);
            app.NoFilesLoadedNode.Text = 'No Files Loaded';
            % Assign Checked Nodes
            app.FileTree.CheckedNodesChangedFcn = createCallbackFcn(app, @FileTreeCheckedNodesChanged, true);
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout14_2 = uigridlayout(app.SettingsTab);
            app.SettingsLabel = uilabel(app.GridLayout14_2);
            app.SettingsLabel.Text = 'Settings';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.EarthViewerGUITeam25Label = uilabel(app.GridLayout3);
            app.EarthViewerGUITeam25Label.Text = 'EarthViewer GUI Team 25';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.DashboardTab = uitab(app.TabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.GridLayout4 = uigridlayout(app.DashboardTab);
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.ImageLoadedLabel = uilabel(app.GridLayout7);
            app.ImageLoadedLabel.Text = 'Image Loaded';
            app.ImagesLabel = uilabel(app.GridLayout7);
            app.ImagesLabel.Text = '-- Images';
            app.GridLayout7_2 = uigridlayout(app.GridLayout6);
            app.TimespanLabel = uilabel(app.GridLayout7_2);
            app.TimespanLabel.Text = 'Timespan';
            app.YearsLabel = uilabel(app.GridLayout7_2);
            app.YearsLabel.Text = '-- Years';
            app.GridLayout7_3 = uigridlayout(app.GridLayout6);
            app.TimeBetweenImagesLabel = uilabel(app.GridLayout7_3);
            app.TimeBetweenImagesLabel.Text = 'Time Between Images';
            app.MonthLabel = uilabel(app.GridLayout7_3);
            app.MonthLabel.Text = '-- Month';
            app.GridLayout7_4 = uigridlayout(app.GridLayout6);
            app.CroppedImageSizeLabel = uilabel(app.GridLayout7_4);
            app.CroppedImageSizeLabel.Text = 'Cropped Image Size';
            app.ImageSizeLabel = uilabel(app.GridLayout7_4);
            app.ImageSizeLabel.Text = '---- x ----';
            app.GridLayout8 = uigridlayout(app.GridLayout4);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.Panel_3 = uipanel(app.GridLayout9);
            app.GridLayout16_2 = uigridlayout(app.Panel_3);
            app.ImageSetInformationLabel = uilabel(app.GridLayout16_2);
            app.ImageSetInformationLabel.Text = 'Image Set Information';
            app.GridLayout22 = uigridlayout(app.GridLayout16_2);
            app.ImageSetNameTagLabel = uilabel(app.GridLayout22);
            app.ImageSetNameTagLabel.Text = 'Image Set Name:';
            app.ImageSetNameLabel = uilabel(app.GridLayout22);
            app.ImageSetNameLabel.Text = 'No Files Loaded';
            app.Panel_4 = uipanel(app.GridLayout9);
            app.GridLayout16_3 = uigridlayout(app.Panel_4);
            app.ImagePreviewSettingLabel = uilabel(app.GridLayout16_3);
            app.ImagePreviewSettingLabel.Text = 'Image Preview Setting';
            app.GridLayout18 = uigridlayout(app.GridLayout16_3);
            app.ImageSelectDropDownLabel = uilabel(app.GridLayout18);
            app.ImageSelectDropDownLabel.Text = 'Image Select';
            app.ImageSelectDropDown = uidropdown(app.GridLayout18);
            app.ImageSelectDropDown.Items = {};
            app.ImageSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageSelectDropDownValueChanged, true);
            app.ImageSelectDropDown.Placeholder = 'None';
            app.ImageSelectDropDown.Value = {};
            app.PreviewModeDropDownLabel = uilabel(app.GridLayout18);
            app.PreviewModeDropDownLabel.Text = 'Preview Mode';
            app.PreviewModeDropDown = uidropdown(app.GridLayout18);
            app.PreviewModeDropDown.Items = {'Original', 'Aligned', 'Cropped'};
            app.PreviewModeDropDown.ValueChangedFcn = createCallbackFcn(app, @PreviewModeDropDownValueChanged, true);
            app.PreviewModeDropDown.Value = 'Original';
            app.QuickSelectSliderLabel = uilabel(app.GridLayout18);
            app.QuickSelectSliderLabel.Text = 'Quick Select';
            app.QuickSelectSlider = uislider(app.GridLayout18);
            app.QuickSelectSlider.ValueChangingFcn = createCallbackFcn(app, @QuickSelectSliderValueChanging, true);
            app.Panel_2 = uipanel(app.GridLayout8);
            app.GridLayout16 = uigridlayout(app.Panel_2);
            app.GridLayoutImagePreview = uigridlayout(app.GridLayout16);
            app.UIAxesImagePreview = uiaxes(app.GridLayoutImagePreview);
            app.UIAxesImagePreview.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.GridLayout19 = uigridlayout(app.GridLayout16);
            app.ImagePreviewLabel = uilabel(app.GridLayout19);
            app.ImagePreviewLabel.Text = 'Image Preview';
            app.PreviewFilenameLabel = uilabel(app.GridLayout19);
            app.PreviewFilenameLabel.Text = ' ';
            app.GridLayout10 = uigridlayout(app.GridLayout4);
            app.Panel_5 = uipanel(app.GridLayout10);
            app.GridLayout16_4 = uigridlayout(app.Panel_5);
            app.GridLayoutStartImage = uigridlayout(app.GridLayout16_4);
            app.GridLayout20 = uigridlayout(app.GridLayout16_4);
            app.StartImageLabel = uilabel(app.GridLayout20);
            app.StartImageLabel.Text = 'Start Image';
            app.StartImgFilenameLabel = uilabel(app.GridLayout20);
            app.StartImgFilenameLabel.Text = ' ';
            app.Panel_6 = uipanel(app.GridLayout10);
            app.GridLayout16_5 = uigridlayout(app.Panel_6);
            app.GridLayoutEndImage = uigridlayout(app.GridLayout16_5);
            app.GridLayout20_2 = uigridlayout(app.GridLayout16_5);
            app.EndImageLabel = uilabel(app.GridLayout20_2);
            app.EndImageLabel.Text = 'End Image';
            app.EndImgFilenameLabel = uilabel(app.GridLayout20_2);
            app.EndImgFilenameLabel.Text = ' ';
            app.Panel_7 = uipanel(app.GridLayout10);
            app.GridLayout16_6 = uigridlayout(app.Panel_7);
            app.GeologicalLocationLabel = uilabel(app.GridLayout16_6);
            app.GeologicalLocationLabel.Text = 'Geological Location';
            app.SliderViewTab = uitab(app.TabGroup);
            app.SliderViewTab.Title = 'Slider View';
            app.GridLayout4_2 = uigridlayout(app.SliderViewTab);
            app.GridLayout8_2 = uigridlayout(app.GridLayout4_2);
            app.Panel_8 = uipanel(app.GridLayout8_2);
            app.Panel_39 = uipanel(app.GridLayout8_2);
            app.GridLayout10_2 = uigridlayout(app.GridLayout4_2);
            app.Panel_11 = uipanel(app.GridLayout10_2);
            app.Panel_12 = uipanel(app.GridLayout10_2);
            app.Panel_13 = uipanel(app.GridLayout10_2);
            app.DifferenceHighlightTab = uitab(app.TabGroup);
            app.DifferenceHighlightTab.Title = 'Difference Highlight';
            app.GridLayout4_3 = uigridlayout(app.DifferenceHighlightTab);
            app.GridLayout8_3 = uigridlayout(app.GridLayout4_3);
            app.GridLayout9_3 = uigridlayout(app.GridLayout8_3);
            app.Panel_22 = uipanel(app.GridLayout9_3);
            app.Panel_14 = uipanel(app.GridLayout8_3);
            app.GridLayout10_3 = uigridlayout(app.GridLayout4_3);
            app.Panel_17 = uipanel(app.GridLayout10_3);
            app.Panel_21 = uipanel(app.GridLayout10_3);
            app.TimelapseTab = uitab(app.TabGroup);
            app.TimelapseTab.Title = 'Timelapse';
            app.GridLayout4_4 = uigridlayout(app.TimelapseTab);
            app.GridLayout8_4 = uigridlayout(app.GridLayout4_4);
            app.GridLayout9_4 = uigridlayout(app.GridLayout8_4);
            app.Panel_24 = uipanel(app.GridLayout9_4);
            app.Panel_23 = uipanel(app.GridLayout8_4);
            app.GridLayout10_4 = uigridlayout(app.GridLayout4_4);
            app.Panel_25 = uipanel(app.GridLayout10_4);
            app.Panel_26 = uipanel(app.GridLayout10_4);
            app.FlickerViewTab = uitab(app.TabGroup);
            app.FlickerViewTab.Title = 'Flicker View';
            app.GridLayout4_5 = uigridlayout(app.FlickerViewTab);
            app.GridLayout8_5 = uigridlayout(app.GridLayout4_5);
            app.GridLayout9_5 = uigridlayout(app.GridLayout8_5);
            app.Panel_28 = uipanel(app.GridLayout9_5);
            app.Panel_27 = uipanel(app.GridLayout8_5);
            app.GridLayout10_5 = uigridlayout(app.GridLayout4_5);
            app.Panel_29 = uipanel(app.GridLayout10_5);
            app.Panel_30 = uipanel(app.GridLayout10_5);
            app.SemanticSegmentationTab = uitab(app.TabGroup);
            app.SemanticSegmentationTab.Title = 'Semantic Segmentation';
            app.GridLayout4_6 = uigridlayout(app.SemanticSegmentationTab);
            app.GridLayout8_6 = uigridlayout(app.GridLayout4_6);
            app.GridLayout9_6 = uigridlayout(app.GridLayout8_6);
            app.Panel_32 = uipanel(app.GridLayout9_6);
            app.Panel_31 = uipanel(app.GridLayout8_6);
            app.GridLayout10_6 = uigridlayout(app.GridLayout4_6);
            app.Panel_33 = uipanel(app.GridLayout10_6);
            app.Panel_34 = uipanel(app.GridLayout10_6);
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.GridLayout4_7 = uigridlayout(app.ReportTab);
            app.GridLayout8_7 = uigridlayout(app.GridLayout4_7);
            app.GridLayout9_7 = uigridlayout(app.GridLayout8_7);
            app.Panel_36 = uipanel(app.GridLayout9_7);
            app.Panel_35 = uipanel(app.GridLayout8_7);
            app.GridLayout10_7 = uigridlayout(app.GridLayout4_7);
            app.Panel_37 = uipanel(app.GridLayout10_7);
            app.Panel_38 = uipanel(app.GridLayout10_7);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cv_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end